classdef NavGraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        LoadBuildingButton   matlab.ui.control.Button
        FloorsDropDownLabel  matlab.ui.control.Label
        FloorsDropDown       matlab.ui.control.DropDown
        BuildingNameLabel    matlab.ui.control.Label
        NodeInfoPanel        matlab.ui.container.Panel
        AddButton            matlab.ui.control.Button
    end


    properties (Access = private)
        floorsInfo = containers.Map; % list of floor map image files
        floorNames = {};
        
        currentFloor % Description
    end

    methods (Access = private)
    
        function parseBuildingFile(app, filename, path)
            addpath(('YAMLMatlab_0.4.3'));
            result = ReadYaml(filename);
            for l = 1 : length(result.floors)
                info = [];
                info.map = imread(fullfile(path, result.floors{l}.walls));
                info.scale = result.floors{l}.scale;
                app.floorsInfo(num2str(result.floors{l}.id)) = info; 
                app.FloorsDropDown.Items{end+1} = num2str(result.floors{l}.id);
            end
        end
        
        
        function visualizeFloor(app, floorNum)
            info = app.floorsInfo(floorNum);
            
            imshow(info.map,'Parent',app.UIAxes);    
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadBuildingButton
        function LoadBuildingButtonPushed(app, event)
            % load yaml file that contains map info (iLocalize format)
            [file,path] = uigetfile('*.yml');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path, file)]);
                parseBuildingFile(app, fullfile(path, file), path);
                disp(app.floorsInfo);
            end
        end

        % Value changed function: FloorsDropDown
        function FloorsDropDownValueChanged(app, event)
            value = app.FloorsDropDown.Value;
            app.currentFloor = value;
            visualizeFloor(app, value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            h = imellipse(app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadBuildingButton = uibutton(app.UIFigure, 'push');
            app.LoadBuildingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBuildingButtonPushed, true);
            app.LoadBuildingButton.Text = 'Load Building';
            app.FloorsDropDownLabel = uilabel(app.UIFigure);
            app.FloorsDropDownLabel.Text = 'Floors';
            app.FloorsDropDown = uidropdown(app.UIFigure);
            app.FloorsDropDown.Items = {};
            app.FloorsDropDown.ValueChangedFcn = createCallbackFcn(app, @FloorsDropDownValueChanged, true);
            app.FloorsDropDown.Value = {};
            app.BuildingNameLabel = uilabel(app.UIFigure);
            app.BuildingNameLabel.Text = 'Building Name: ';
            app.NodeInfoPanel = uipanel(app.UIFigure);
            app.NodeInfoPanel.Title = 'Node Info';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NavGraph

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end